classdef NumberPlateReaderApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NumberPlateReaderUIFigure  matlab.ui.Figure
        load                       matlab.ui.control.Image
        res                        matlab.ui.control.Image
        cut                        matlab.ui.control.Image
        btn                        matlab.ui.control.Button
        CutPlateLabel              matlab.ui.control.Label
        OriginalImageLabel         matlab.ui.control.Label
        TheNumberisLabel           matlab.ui.control.Label
        CloseAppButton             matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: btn, load
        function btnButtonPushed(app, event)
            [baseFileName,folder] = uigetfile('*.*','Specify an image file','on');
            fullimageFileName = fullfile(folder,baseFileName);
            app.load.ImageSource = fullimageFileName;
            pic = imread(fullimageFileName);
            
            Image = Cut_Plate(pic);
            Path = './resultImages/cutPlate.png';
            imwrite(Image,Path);
            app.cut.ImageSource = Path;
            cutPlate = imread(Path);
            
            Image = Plate_Reader(cutPlate);
            Path = './resultImages/final_output.png';
            imwrite(Image,Path);
            app.res.ImageSource = Path;
            outputResult = imread(Path);
            
            app.btn.Enable = false;
        end

        % Button pushed function: CloseAppButton
        function CloseAppButtonPushed(app, event)
            delete(app.NumberPlateReaderUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NumberPlateReaderUIFigure = uifigure('Visible', 'off');
            app.NumberPlateReaderUIFigure.Name = 'Number Plate Reader';
            app.load = uiimage(app.NumberPlateReaderUIFigure);
            app.load.ImageClickedFcn = createCallbackFcn(app, @btnButtonPushed, true);
            app.load.Tooltip = {''};
            app.load.ImageSource = 'white.png';
            app.res = uiimage(app.NumberPlateReaderUIFigure);
            app.res.ImageSource = 'white.png';
            app.cut = uiimage(app.NumberPlateReaderUIFigure);
            app.cut.ImageSource = 'white.png';
            app.btn = uibutton(app.NumberPlateReaderUIFigure, 'push');
            app.btn.ButtonPushedFcn = createCallbackFcn(app, @btnButtonPushed, true);
            app.btn.Text = 'Load Image';
            app.CutPlateLabel = uilabel(app.NumberPlateReaderUIFigure);
            app.CutPlateLabel.Text = 'Cut Plate';
            app.OriginalImageLabel = uilabel(app.NumberPlateReaderUIFigure);
            app.OriginalImageLabel.Text = 'Original Image';
            app.TheNumberisLabel = uilabel(app.NumberPlateReaderUIFigure);
            app.TheNumberisLabel.Text = 'The Number is:';
            app.CloseAppButton = uibutton(app.NumberPlateReaderUIFigure, 'push');
            app.CloseAppButton.ButtonPushedFcn = createCallbackFcn(app, @CloseAppButtonPushed, true);
            app.CloseAppButton.Text = 'Close App';
            % Show the figure after all components are created
            app.NumberPlateReaderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NumberPlateReaderApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NumberPlateReaderUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NumberPlateReaderUIFigure)
        end
    end
end